classdef OpenConMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpenConMapUIFigure              matlab.ui.Figure
        CurvePanel                      matlab.ui.container.Panel
        StartpointsEditField            matlab.ui.control.EditField
        StartpointsEditFieldLabel       matlab.ui.control.Label
        SubcurvesEditField              matlab.ui.control.EditField
        SubcurvesEditFieldLabel         matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        PathEditField                   matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        SolveButton                     matlab.ui.control.Button
        RateEditField                   matlab.ui.control.EditField
        RateEditFieldLabel              matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.EditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        completedEditField              matlab.ui.control.EditField
        completedEditFieldLabel         matlab.ui.control.Label
        InitializeButton                matlab.ui.control.Button
        SeriesorderEditField            matlab.ui.control.EditField
        SeriesorderEditFieldLabel       matlab.ui.control.Label
        IterationspeedEditField         matlab.ui.control.EditField
        IterationspeedEditFieldLabel    matlab.ui.control.Label
        NumberofverticesEditField       matlab.ui.control.EditField
        NumberofverticesEditFieldLabel  matlab.ui.control.Label
        CorrespondingpointEditField     matlab.ui.control.EditField
        CorrespondingpointEditFieldLabel  matlab.ui.control.Label
        PlottypeButtonGroup             matlab.ui.container.ButtonGroup
        ExitButton                      matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        ErrorButton                     matlab.ui.control.RadioButton
        MappingButton                   matlab.ui.control.RadioButton
        PolygonButton                   matlab.ui.control.RadioButton
        BoundaryButton                  matlab.ui.control.RadioButton
        PlotparametersPanel             matlab.ui.container.Panel
        ThetaEditField                  matlab.ui.control.EditField
        ThetaEditFieldLabel             matlab.ui.control.Label
        YrangeEditField                 matlab.ui.control.EditField
        YrangeEditFieldLabel            matlab.ui.control.Label
        XrangeEditField                 matlab.ui.control.EditField
        XrangeEditFieldLabel            matlab.ui.control.Label
        RhoEditField                    matlab.ui.control.EditField
        RhoEditFieldLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)

            global F X N1 N2 N3 N4 Lambda eff eff_r c0 zC S zz cc

            F=eval(app.SubcurvesEditField.Value);
            X=str2num(app.StartpointsEditField.Value);
            
            N1=str2num(app.IterationsEditField.Value); % Iteration time
            N2=str2num(app.SeriesorderEditField.Value); % Series order
            N3=str2num(app.CorrespondingpointEditField.Value); % Number of corresponding points
            N4=str2num(app.NumberofverticesEditField.Value); % Number of vertices

            Lambda=str2num(app.IterationspeedEditField.Value); % Iteration speed

            eff=str2num(app.RateEditField.Value); % Efficiency
            zC = Initialize(F, X, N4);

            eta=linspace(0,1,5000);
            zz=Boundary(eta);
            
            app.completedEditField.Value='No';

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)

            global C epsilon N1 N2 N3 Lambda eff zC

            app.completedEditField.Value='No';
            [C,epsilon]=ConformalSolve(zC,N1,N2,N3,Lambda,eff);

            app.completedEditField.Value='Yes';

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            xrange=eval(app.XrangeEditField.Value);
            yrange=eval(app.YrangeEditField.Value);

            global zz C epsilon zC C0

            if app.BoundaryButton.Value
                
                app.UIAxes.Title.String='Boundary';
                cla(app.UIAxes)
                hold(app.UIAxes,'on')
                box(app.UIAxes,'on')
                grid(app.UIAxes,'on')
                axis(app.UIAxes,'image')
                app.UIAxes.XLim=[xrange(1),xrange(end)];
                app.UIAxes.YLim=[yrange(1),yrange(end)];
                app.UIAxes.XTick=xrange;
                app.UIAxes.YTick=yrange;
                app.UIAxes.YScale='linear';

                plot(app.UIAxes,real(zz),imag(zz),'LineWidth',2,'Color',[0, 114, 178]/255);

                for k1=round(linspace(1,length(zz),80))
                    plot(app.UIAxes,real(zz(k1)),imag(zz(k1)),'o','LineWidth',2,'Color',[0, 158, 115]/255)
                    pause(2/80)
                end

            elseif app.PolygonButton.Value
                
                app.UIAxes.Title.String='Polygon';
                cla(app.UIAxes)
                box(app.UIAxes,'on')
                axis(app.UIAxes,'image')
                app.UIAxes.XLim=[xrange(1),xrange(end)];
                app.UIAxes.YLim=[yrange(1),yrange(end)];
                app.UIAxes.XTick=xrange;
                app.UIAxes.YTick=yrange;
                app.UIAxes.YScale='linear';

                eta=linspace(0,1,5000);
                zz=Boundary(eta);

                plot(app.UIAxes,real(zz),imag(zz),'-','LineWidth',2,'Color',[0, 114, 178]/255);
                plot(app.UIAxes,real([zC,zC(1)]),imag([zC,zC(1)]),'s-','LineWidth',2,'Color',[213, 94, 0]/255);
                
            elseif app.MappingButton.Value

                C0=C(end:-1:1).';
                theta0=deg2rad(eval(app.ThetaEditField.Value));
                rho0=eval(app.RhoEditField.Value);

                [rho,theta]=meshgrid(linspace(min(rho0),max(rho0),1000),theta0);
                rho=1./rho;
                zeta=rho.*exp(i*theta);
                omegau=C0;
                omegad=[1 0].';
                z1=(polyval(omegau,zeta)./(polyval(omegad,zeta)));

                [rho,theta]=meshgrid(rho0,linspace(0,2*pi,1000));
                rho=1./rho;
                zeta=rho.*exp(i*theta);
                omegau=C0;
                omegad=[1 0].';
                z2=(polyval(omegau,zeta)./(polyval(omegad,zeta)));

                app.UIAxes.Title.String='Mapping';
                cla(app.UIAxes)
                hold(app.UIAxes,'on')
                box(app.UIAxes,'on')
                grid(app.UIAxes,'on')
                axis(app.UIAxes,'image')
                app.UIAxes.XLim=[xrange(1),xrange(end)];
                app.UIAxes.YLim=[yrange(1),yrange(end)];
                app.UIAxes.XTick=xrange;
                app.UIAxes.YTick=yrange;
                app.UIAxes.YScale='linear';

                plot(app.UIAxes,real(z1)',imag(z1)','LineWidth',1.5,'Color',[86, 180, 233]/255);
                plot(app.UIAxes,real(z2),imag(z2),'LineWidth',1.5,'Color',[86, 180, 233]/255);
                
                eta=linspace(0,1,5000);
                zz=Boundary(eta);

                plot(app.UIAxes,real(zz),imag(zz),'-','LineWidth',2,'Color',[0, 114, 178]/255);

            elseif app.ErrorButton.Value

                app.UIAxes.Title.String='Error';
                cla(app.UIAxes)
                hold(app.UIAxes,'on')
                axis(app.UIAxes,'normal');
                app.UIAxes.YScale='log';
                xlim(app.UIAxes,"padded");
                ylim(app.UIAxes,"padded");
                xticks(app.UIAxes,'auto');
                yticks(app.UIAxes,'auto');

                if iscell(epsilon)
                    for k1=1:length(epsilon)
                        plot(app.UIAxes,epsilon{k1},'-s','LineWidth',2);
                    end
                else 
                    plot(app.UIAxes,epsilon,'-s','LineWidth',2);
                end

                
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            global C
            path_d=app.PathEditField.Value;
            xlswrite([path_d,'\Series_coefficient.xls'],C,'A1');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OpenConMapUIFigure = uifigure('Visible', 'off');
            app.OpenConMapUIFigure.Name = 'OpenConMap';
            app.UIAxes = uiaxes(app.OpenConMapUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotparametersPanel = uipanel(app.OpenConMapUIFigure);
            app.PlotparametersPanel.Title = 'Plot parameters';
            app.RhoEditFieldLabel = uilabel(app.PlotparametersPanel);
            app.RhoEditFieldLabel.Text = 'Rho';
            app.RhoEditField = uieditfield(app.PlotparametersPanel, 'text');
            app.RhoEditField.Value = '1:0.2:4';
            app.XrangeEditFieldLabel = uilabel(app.PlotparametersPanel);
            app.XrangeEditFieldLabel.Text = 'X range';
            app.XrangeEditField = uieditfield(app.PlotparametersPanel, 'text');
            app.XrangeEditField.Value = '-25:5:25';
            app.YrangeEditFieldLabel = uilabel(app.PlotparametersPanel);
            app.YrangeEditFieldLabel.Text = 'Y range';
            app.YrangeEditField = uieditfield(app.PlotparametersPanel, 'text');
            app.YrangeEditField.Value = '-8:2:8';
            app.ThetaEditFieldLabel = uilabel(app.PlotparametersPanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.PlotparametersPanel, 'text');
            app.ThetaEditField.Value = '0:5:360';
            app.PlottypeButtonGroup = uibuttongroup(app.OpenConMapUIFigure);
            app.PlottypeButtonGroup.Title = ' Plot type';
            app.BoundaryButton = uiradiobutton(app.PlottypeButtonGroup);
            app.BoundaryButton.Text = 'Boundary';
            app.BoundaryButton.Value = true;
            app.PolygonButton = uiradiobutton(app.PlottypeButtonGroup);
            app.PolygonButton.Text = 'Polygon';
            app.MappingButton = uiradiobutton(app.PlottypeButtonGroup);
            app.MappingButton.Text = 'Mapping';
            app.ErrorButton = uiradiobutton(app.PlottypeButtonGroup);
            app.ErrorButton.Text = 'Error';
            app.PlotButton = uibutton(app.PlottypeButtonGroup, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ExitButton = uibutton(app.PlottypeButtonGroup, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ParametersPanel = uipanel(app.OpenConMapUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.CorrespondingpointEditFieldLabel = uilabel(app.ParametersPanel);
            app.CorrespondingpointEditFieldLabel.Text = 'Corresponding point';
            app.CorrespondingpointEditField = uieditfield(app.ParametersPanel, 'text');
            app.CorrespondingpointEditField.Value = '2000';
            app.NumberofverticesEditFieldLabel = uilabel(app.ParametersPanel);
            app.NumberofverticesEditFieldLabel.Text = 'Number of vertices';
            app.NumberofverticesEditField = uieditfield(app.ParametersPanel, 'text');
            app.NumberofverticesEditField.Value = '80';
            app.IterationspeedEditFieldLabel = uilabel(app.ParametersPanel);
            app.IterationspeedEditFieldLabel.Text = 'Iteration speed';
            app.IterationspeedEditField = uieditfield(app.ParametersPanel, 'text');
            app.IterationspeedEditField.Value = '0.6';
            app.SeriesorderEditFieldLabel = uilabel(app.ParametersPanel);
            app.SeriesorderEditFieldLabel.Text = 'Series order';
            app.SeriesorderEditField = uieditfield(app.ParametersPanel, 'text');
            app.SeriesorderEditField.Value = '500';
            app.InitializeButton = uibutton(app.ParametersPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.completedEditFieldLabel = uilabel(app.ParametersPanel);
            app.completedEditFieldLabel.Text = 'completed?';
            app.completedEditField = uieditfield(app.ParametersPanel, 'text');
            app.completedEditField.Value = 'No';
            app.IterationsEditFieldLabel = uilabel(app.ParametersPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.ParametersPanel, 'text');
            app.IterationsEditField.Value = '50';
            app.RateEditFieldLabel = uilabel(app.ParametersPanel);
            app.RateEditFieldLabel.Text = 'Rate';
            app.RateEditField = uieditfield(app.ParametersPanel, 'text');
            app.RateEditField.Value = '1e-3';
            app.SolveButton = uibutton(app.ParametersPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.SaveButton = uibutton(app.ParametersPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PathEditFieldLabel = uilabel(app.ParametersPanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.ParametersPanel, 'text');
            app.PathEditField.Value = 'E:\Matlab';
            app.CurvePanel = uipanel(app.OpenConMapUIFigure);
            app.CurvePanel.Title = 'Curve';
            app.SubcurvesEditFieldLabel = uilabel(app.CurvePanel);
            app.SubcurvesEditFieldLabel.Text = 'Sub-curves';
            app.SubcurvesEditField = uieditfield(app.CurvePanel, 'text');
            app.SubcurvesEditField.Value = '{@(x) sin(x)+0.5, @(x) -x+6*pi, @(x) sin(x)-0.5, @(x) -x-6*pi}';
            app.StartpointsEditFieldLabel = uilabel(app.CurvePanel);
            app.StartpointsEditFieldLabel.Text = 'Start points';
            app.StartpointsEditField = uieditfield(app.CurvePanel, 'text');
            app.StartpointsEditField.Value = '[-19.100874546062855 + 0.251318624524098i 18.598237297014663 + 0.251318624524097i 19.100874546062855 - 0.251318624524098i -18.598237297014663 - 0.251318624524096i]';
            % Show the figure after all components are created
            app.OpenConMapUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenConMap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OpenConMapUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpenConMapUIFigure)
        end
    end
end